classdef CompareSamplesParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                     matlab.ui.Figure
        Sample1PathEditField           matlab.ui.control.EditField
        BrowseSample1Button            matlab.ui.control.Button
        Sample2PathEditField           matlab.ui.control.EditField
        BrowseSample2Button            matlab.ui.control.Button
        Sample1NameEditField           matlab.ui.control.EditField
        Sample2NameEditField           matlab.ui.control.EditField
        PillardiameterEditFieldLabel   matlab.ui.control.Label
        ComparisonType                 matlab.ui.container.ButtonGroup
        FullComparison                 matlab.ui.control.RadioButton
        OnePillarComparison            matlab.ui.control.RadioButton
        FullComparisonOptions          matlab.ui.container.ButtonGroup
        SuccessivePlots                matlab.ui.control.RadioButton
        CombinedPlots                  matlab.ui.control.RadioButton
        FilenameEditFieldLabel         matlab.ui.control.Label
        PillarDiameterEditField        matlab.ui.control.NumericEditField
        SavingDirectoryEditField       matlab.ui.control.EditField
        SavingdirectoryEditFieldLabel  matlab.ui.control.Label
        BrowseSavingDirectoryButton    matlab.ui.control.Button
        Sample2nameEditFieldLabel      matlab.ui.control.Label
        Sample1nameEditFieldLabel      matlab.ui.control.Label
        Sample2pathLabel               matlab.ui.control.Label
        Sample1pathLabel               matlab.ui.control.Label
        ComparisonFilenameEditField    matlab.ui.control.EditField
        OKButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        sample1Path
        sample2Path
        sample1Name
        sample2Name
        fullComparison
        stringingMode
        pillarDiameter
        comparisonSavingDir
        comparisonFileName

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sample1Path, sample2Path, sample1Name, sample2Name, fullComparison, stringingMode, pillarDiameter, comparisonSavingDir, comparisonFileName)
            app.Sample1PathEditField.Value = sample1Path;
            app.Sample2PathEditField.Value = sample2Path;
            app.Sample1NameEditField.Value = sample1Name;
            app.Sample2NameEditField.Value = sample2Name;
            if strcmp(stringingMode,"Combined")
                app.CombinedPlots.Value = true;
            else
                app.SuccessivePlots.Value = true;
            end
            if strcmp(fullComparison,"Full")
                app.FullComparison.Value = true;
            else
                app.OnePillarComparison.Value = true;
            end
            app.PillarDiameterEditField.Value = pillarDiameter;
            app.SavingDirectoryEditField.Value = comparisonSavingDir;
            app.ComparisonFilenameEditField.Value = comparisonFileName;

            ComparisonTypeCallback(app)

            app.mainapp = mainapp;
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            app.mainapp.sample1Path = app.Sample1NameEditField.Value;
            app.mainapp.sample2Path = app.Sample2NameEditField.Value;
            app.mainapp.sample1Name = app.Sample1NameEditField.Value;
            app.mainapp.sample2Name = app.Sample2NameEditField.Value;
            if app.CombinedPlots.Value == true
                app.mainapp.stringingMode = "Combined";
            else
                app.mainapp.stringingMode = "Successive";
            end
            if app.FullComparison.Value == true
                app.mainapp.fullComparison = "Full";
            else
                app.mainapp.fullComparison = "One pillar";
            end
            app.mainapp.pillarDiameter = app.PillarDiameterEditField.Value;
            app.mainapp.comparisonSavingDir = app.SavingDirectoryEditField.Value;
            app.mainapp.comparisonFileName = app.ComparisonFilenameEditField.Value;

            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end

        % Button pushed function: BrowseSample1Button
        function BrowseSample1Callback(app, event)
            app.sample1Path = uigetdir('', "Select the series directory.");

            if app.sample1Path == 0 | isempty(app.sample1Path)
                disp('No directory selected.')
                return
            end
            [~, app.Sample1PathEditField.Value] = fileparts(app.sample1Path);
            app.Sample1NameEditField.Value = app.Sample1PathEditField.Value;
        end

        % Button pushed function: BrowseSample2Button
        function BrowseSample2Callback(app, event)
            app.sample2Path = uigetdir('', "Select the series directory.");

            if app.sample2Path == 0 | isempty(app.sample2Path)
                disp('No directory selected.')
                return
            end
            [~, app.Sample2PathEditField.Value] = fileparts(app.sample2Path);
            app.Sample2NameEditField.Value = app.Sample2PathEditField.Value;
        end

        % Selection changed function: ComparisonType
        function ComparisonTypeCallback(app, event)
            if app.FullComparison.Value == true
                app.PillarDiameterEditField.Enable = "off";
                app.FullComparisonOptions.Enable = "on";
            else
                app.PillarDiameterEditField.Enable = "on";
                app.FullComparisonOptions.Enable = "off";
            end
        end

        % Button pushed function: BrowseSavingDirectoryButton
        function BrowseSavingDirectoryCallback(app, event)
            app.comparisonSavingDir = uigetdir('', "Select the series directory.");

            if app.comparisonSavingDir == 0 | isempty(app.comparisonSavingDir)
                disp('No directory selected.')
                return
            end
            [~, app.SavingDirectoryEditField.Value] = fileparts(app.comparisonSavingDir);
            app.ComparisonFilenameEditField.Value = app.SavingDirectoryEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Comparison Parameters';
            app.MainWindow.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-DP_app.png');
            app.CancelButton = uibutton(app.MainWindow, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.MainWindow, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.ComparisonFilenameEditField = uieditfield(app.MainWindow, 'text');
            app.Sample1pathLabel = uilabel(app.MainWindow);
            app.Sample1pathLabel.Text = 'Sample 1 path';
            app.Sample2pathLabel = uilabel(app.MainWindow);
            app.Sample2pathLabel.Text = 'Sample 2 path';
            app.Sample1nameEditFieldLabel = uilabel(app.MainWindow);
            app.Sample1nameEditFieldLabel.Text = 'Sample 1 name';
            app.Sample2nameEditFieldLabel = uilabel(app.MainWindow);
            app.Sample2nameEditFieldLabel.Text = 'Sample 2 name';
            app.BrowseSavingDirectoryButton = uibutton(app.MainWindow, 'push');
            app.BrowseSavingDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSavingDirectoryCallback, true);
            app.BrowseSavingDirectoryButton.Text = 'Browse...';
            app.SavingdirectoryEditFieldLabel = uilabel(app.MainWindow);
            app.SavingdirectoryEditFieldLabel.Text = 'Saving directory';
            app.SavingDirectoryEditField = uieditfield(app.MainWindow, 'text');
            app.SavingDirectoryEditField.Editable = 'off';
            app.PillarDiameterEditField = uieditfield(app.MainWindow, 'numeric');
            app.PillarDiameterEditField.Value = 10;
            app.FilenameEditFieldLabel = uilabel(app.MainWindow);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FullComparisonOptions = uibuttongroup(app.MainWindow);
            app.FullComparisonOptions.Title = 'Options for full comparison';
            app.CombinedPlots = uiradiobutton(app.FullComparisonOptions);
            app.CombinedPlots.Tooltip = {'Combination of all the data together in one plot'};
            app.CombinedPlots.Text = 'Combination';
            app.SuccessivePlots = uiradiobutton(app.FullComparisonOptions);
            app.SuccessivePlots.Tooltip = {'Successive plots for each pillar diameter'};
            app.SuccessivePlots.Text = 'Succession';
            app.SuccessivePlots.Value = true;
            app.ComparisonType = uibuttongroup(app.MainWindow);
            app.ComparisonType.SelectionChangedFcn = createCallbackFcn(app, @ComparisonTypeCallback, true);
            app.ComparisonType.Title = 'Comparison type';
            app.OnePillarComparison = uiradiobutton(app.ComparisonType);
            app.OnePillarComparison.Tooltip = {'Combination of all the data together in one plot'};
            app.OnePillarComparison.Text = '1 pillar';
            app.OnePillarComparison.Value = true;
            app.FullComparison = uiradiobutton(app.ComparisonType);
            app.FullComparison.Tooltip = {'Successive plots for each pillar diameter'};
            app.FullComparison.Text = 'Full comparison';
            app.PillardiameterEditFieldLabel = uilabel(app.MainWindow);
            app.PillardiameterEditFieldLabel.Text = 'Pillar diameter ';
            app.Sample2NameEditField = uieditfield(app.MainWindow, 'text');
            app.Sample1NameEditField = uieditfield(app.MainWindow, 'text');
            app.BrowseSample2Button = uibutton(app.MainWindow, 'push');
            app.BrowseSample2Button.ButtonPushedFcn = createCallbackFcn(app, @BrowseSample2Callback, true);
            app.BrowseSample2Button.Text = 'Browse...';
            app.Sample2PathEditField = uieditfield(app.MainWindow, 'text');
            app.Sample2PathEditField.Editable = 'off';
            app.BrowseSample1Button = uibutton(app.MainWindow, 'push');
            app.BrowseSample1Button.ButtonPushedFcn = createCallbackFcn(app, @BrowseSample1Callback, true);
            app.BrowseSample1Button.Text = 'Browse...';
            app.Sample1PathEditField = uieditfield(app.MainWindow, 'text');
            app.Sample1PathEditField.Editable = 'off';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompareSamplesParameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end