classdef DisplacementAnalysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DisplacementAnalysisFigure  matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        OpenCoordinatesMenu         matlab.ui.container.Menu
        SaveCoordinatesMenu         matlab.ui.container.Menu
        CleanMarkersMenu            matlab.ui.container.Menu
        Averagestrain1DMenu         matlab.ui.container.Menu
        LateralControlPanel         matlab.ui.container.Panel
        ResetLimitsPushButton       matlab.ui.control.Button
        MinX                        matlab.ui.control.NumericEditField
        MaxX                        matlab.ui.control.NumericEditField
        MaxyaxisEditFieldLabel      matlab.ui.control.Label
        MinY                        matlab.ui.control.NumericEditField
        MinyaxisEditFieldLabel      matlab.ui.control.Label
        MaxxaxisLabel               matlab.ui.control.Label
        MinxaxisEditFieldLabel_2    matlab.ui.control.Label
        MaxY                        matlab.ui.control.NumericEditField
        ImageControlPanel           matlab.ui.container.Panel
        MaxEditFieldLabel           matlab.ui.control.Label
        ImageEditFieldLabel_2       matlab.ui.control.Label
        MinEditFieldLabel           matlab.ui.control.Label
        ImageSlider                 matlab.ui.control.Slider
        MinImage                    matlab.ui.control.NumericEditField
        CurrentImage                matlab.ui.control.NumericEditField
        MaxImage                    matlab.ui.control.NumericEditField
        ThreeDVisualizationPanel    matlab.ui.container.ButtonGroup
        MeshButton                  matlab.ui.control.CheckBox
        DataPointsButton            matlab.ui.control.CheckBox
        XZPlaneButton               matlab.ui.control.CheckBox
        YZPlaneButton               matlab.ui.control.CheckBox
        VectorButton                matlab.ui.control.CheckBox
        SwitchOrientationButton     matlab.ui.control.CheckBox
        AverageStrainButton         matlab.ui.control.CheckBox
        Visualization               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ValidX = [];
        ValidY = [];
        StdX = [];
        StdY = [];
        CorrCoef = [];
        % Backup
        ValidXBackup = [];
        ValidYBackup = [];
    end
    
    properties (Access = private)
        Directions = ['x','y'];

        % Log files
        LogFileName='postproc.log';
        % Meta data (not used from main GUI for now, but from RT module)
        MetaData = GetMetaData('metadata.log');

        % Default values of max/min images
        defaultMinImage
        defaultMaxImage
        defaultMinX
        defaultMaxX
        defaultMinY
        defaultMaxY

        % Video variables
        VideoStopped
        VideoCount
    end
   
    methods (Access = private)
        function [ValidX, ValidY] = AverageBaseCoordinates(~, ValidX, ValidY)
            % Average base coordinates (ValidX, ValidY)
            
            Prompt={'How many images would you like to combine as a base image?'};
            DlgTitle='Input number of images:';
            DefValue={'5'};
            Answer=inputdlg(Prompt,DlgTitle,1,DefValue);
            NumOfBaseImages=str2double(cell2mat(Answer(1)));
        
            if NumOfBaseImages>size(ValidX,2)
                disp('Number of base images must not be larger than number of total images!');
            else
                BaseImageMeanX=mean(ValidX(:,1:NumOfBaseImages),2);
                ValidX(:,1:NumOfBaseImages-1)=[];
                ValidX(:,1)=BaseImageMeanX;
                BaseImageMeanY=mean(ValidY(:,1:NumOfBaseImages),2);
                ValidY(:,1:NumOfBaseImages-1)=[];
                ValidY(:,1)=BaseImageMeanY;
            end
        end

        function EnableMenuEntries(app)
            % Enable other menu controls
            for component = 1:length(app.DisplacementAnalysisFigure.Children)
                if isprop(app.DisplacementAnalysisFigure.Children(component),'Enable')
                    app.DisplacementAnalysisFigure.Children(component).Enable = 'on';
                    if isprop(app.DisplacementAnalysisFigure.Children(component),'Children')
                        for subcomponent = 1:length(app.DisplacementAnalysisFigure.Children(component).Children)
                            app.DisplacementAnalysisFigure.Children(component).Children(subcomponent).Enable = 'on';
                        end
                    end
                end
            end
        end
        
        function [MinX, MaxX, MinY, MaxY] = GetMinMax(~, X, Y)
            MinX=min(min(X));
            MaxX=max(max(X));
            MinY=min(min(Y));
            MaxY=max(max(Y));
        end
        
        function OpenCoordinatesFcn(app)
            % Open coordinates (ValidX, ValidY)
            Delimiter = '\t';

            [ValidXName,ValidXPath] = uigetfile('*.dat','Open validx.dat');
            if ValidXName==0
                disp('You did not select a file!');
                return
            end
            cd(ValidXPath);
            app.ValidX=importdata(ValidXName,Delimiter);

            [ValidYName,ValidYPath] = uigetfile('*.dat','Open validy.dat');
            if ValidYName==0
                disp('You did not select a file!');
                return
            end
            cd(ValidYPath);
            app.ValidY=importdata(ValidYName,Delimiter);
        
            if ~exist('app.StdX','var')
                app.StdX=zeros(size(app.ValidX));
                app.StdY=zeros(size(app.ValidY));
            end
        
            if ~exist('app.CorrCoef','var')
                app.CorrCoef=zeros(size(app.ValidX));
            end
        end
        
        function OpenCorrCoef(app)
            Delimiter = '\t';
            if isempty(app.CorrCoef)
                [CorrCoefName,CorrCoefPath] = uigetfile('*.dat','Open corrcoef.dat');
                if CorrCoefName==0
                    disp('You did not select a file!');
                    return
                end
                cd(CorrCoefPath);
                app.CorrCoef = importdata(CorrCoefName,Delimiter);
            end
        end
        
        function OpenStdDevs(app)
            Delimiter = '\t';
            % if isempty(app.StdX)
                [StdXName,StdXPath] = uigetfile('*.dat','Open stdx.dat');
                if StdXName==0
                    disp('You did not select a file!');
                    return
                end
                cd(StdXPath);
                app.StdX=importdata(StdXName,Delimiter);
            % end
            % if isempty(app.StdY)
                [StdYName,StdYPath] = uigetfile('*.dat','Open stdy.dat');
                if StdYName==0
                    disp('You did not select a file!');
                    return
                end
                cd(StdYPath);
                app.StdY=importdata(StdYName,Delimiter);
            % end
        end
        
        function ResetMinMax(app)
            [app.MinX.Value,app.MaxX.Value,app.MinY.Value,app.MaxY.Value] = GetMinMax(app, app.ValidX, app.ValidY);
            
            app.CurrentImage.Value=1;
            app.MinImage.Value=1;
            app.MaxImage.Value = size(app.ValidX,2);
            app.defaultMinImage = 1;
            app.defaultMaxImage = size(app.ValidX,2);

            if (app.MaxImage.Value > app.MinImage.Value)
                app.ImageSlider.Enable = 'on';
                app.ImageSlider.Limits = [app.MinImage.Value app.MaxImage.Value];
                app.ImageSlider.Value = app.CurrentImage.Value;
            else
                app.ImageSlider.Enable = 'off';
            end
        end
        
        function SaveCoordinates(~, ValidX, ValidY, StdX, StdY, CorrCoef)
            [FileName,PathName] = uiputfile('validx_corr.dat','Save validx');
            if FileName==0
                disp('You did not save your file!');
            else
                cd(PathName);      
                save(FileName,'ValidX','-ascii','-tabs');
            end  
                
            [FileName,PathName] = uiputfile('validy_corr.dat','Save validy');
            if FileName==0
                disp('You did not save your file!');
            else
                cd(PathName);
                save(FileName,'ValidY','-ascii','-tabs');
            end
            
            if exist('StdX','var')
                [FileName,PathName] = uiputfile('stdx_corr.dat','Save stdx');
                if FileName==0
                    disp('You did not save your file!');
                else
                    cd(PathName);      
                    save(FileName,'StdX','-ascii','-tabs');
                end  
        
                [FileName,PathName] = uiputfile('stdy_corr.dat','Save stdy');
                if FileName==0
                    disp('You did not save your file!');
                else
                    cd(PathName);      
                    save(FileName,'StdY','-ascii','-tabs');
                end  
            end
            
             if exist('CorrCoef','var')
                [FileName,PathName] = uiputfile('corrcoef_corr.dat','Save corrcoef');
                if FileName==0
                    disp('You did not save your file!');
                else
                    cd(PathName);      
                    save(FileName,'CorrCoef','-ascii','-tabs');
                end
             end
        end
        
        function Visualize3D(app)
            % Update 3D visualization
             
            set(groot,'CurrentFigure',app.DisplacementAnalysisFigure);

            FineGridSizeX=10*round(min(min(app.ValidX(:,app.CurrentImage.Value)))/10):10:10*round(max(max(app.ValidX(:,app.CurrentImage.Value)))/10);
            FineGridSizeY=10*round(min(min(app.ValidY(:,app.CurrentImage.Value)))/10):10:10*round(max(max(app.ValidY(:,app.CurrentImage.Value)))/10);
            CoarseGridSizeX=10*round(min(min(app.ValidX(:,app.CurrentImage.Value)))/10):100:10*round(max(max(app.ValidX(:,app.CurrentImage.Value)))/10);
            CoarseGridSizeY=10*round(min(min(app.ValidY(:,app.CurrentImage.Value)))/10):100:10*round(max(max(app.ValidY(:,app.CurrentImage.Value)))/10);
        
            [XIFine,YIFine]=meshgrid(FineGridSizeX,FineGridSizeY);
            [XICoarse,YICoarse]=meshgrid(CoarseGridSizeX,CoarseGridSizeY);
        
            DisplX=GetMeanDisplacement(app.ValidX);
            DisplY=GetMeanDisplacement(app.ValidY);
            % [app.MinX.Value,app.MaxX.Value,app.MinY.Value,app.MaxY.Value]=GetMinMax(app, app.ValidX,app.ValidY);
            [MinDisplX,MaxDisplX,MinDisplY,MaxDisplY]=GetMinMax(app, DisplX,DisplY);
        
            FX = TriScatteredInterp(app.ValidX(:,app.CurrentImage.Value), app.ValidY(:,app.CurrentImage.Value), DisplX(:,app.CurrentImage.Value));
            FY = TriScatteredInterp(app.ValidX(:,app.CurrentImage.Value),app.ValidY(:,app.CurrentImage.Value),DisplY(:,app.CurrentImage.Value));
            ZIXFine=FX(XIFine,YIFine);
            ZIYFine=FY(XIFine,YIFine);
            ZIXCoarse=FX(XICoarse,YICoarse);
            ZIYCoarse=FY(XICoarse,YICoarse);
        
            % Switch orientation
            Positions = {'x-position [pixel]','y-position [pixel]'};
            if app.SwitchOrientationButton.Value == 0
                ZIFine=ZIXFine;
                Displ=DisplX;
                MinDispl=MinDisplX;
                MaxDispl=MaxDisplX;
                XLabel=Positions{1,1};
                YLabel=Positions{1,2};
            else
                ZIFine=ZIYFine;
                Displ=DisplY;
                MinDispl=MinDisplY;
                MaxDispl=MaxDisplY;
                XLabel=Positions{1,2};
                YLabel=Positions{1,1};
            end
        
            % Mesh
            if app.MeshButton.Value == 1
                mesh(XIFine,YIFine,ZIFine);
                hold on
            end
        
            BackYPlanePlot=ones(size(app.ValidY))*app.MinY.Value;
            BackXPlanePlot=ones(size(app.ValidX))*app.MinX.Value;
        
            % Show data points
            if app.DataPointsButton.Value == 1
                plot3(app.Visualization,app.ValidX(:,app.CurrentImage.Value),app.ValidY(:,app.CurrentImage.Value),Displ(:,app.CurrentImage.Value),'.b');
                hold on
            end
        
            % XZ plane
            if app.XZPlaneButton.Value == 1
                plot3(app.Visualization,BackXPlanePlot(:,app.CurrentImage.Value),app.ValidY(:,app.CurrentImage.Value),Displ(:,app.CurrentImage.Value),'.g');
                hold on
            end
        
            % YZ plane
            if app.YZPlaneButton.Value == 1
                plot3(app.Visualization,app.ValidX(:,app.CurrentImage.Value),BackYPlanePlot(:,app.CurrentImage.Value),Displ(:,app.CurrentImage.Value),'.g');
                hold on
            end
        
            % Vector
            if app.VectorButton.Value == 1
                quiver(app.Visualization,XICoarse,YICoarse,ZIXCoarse,ZIYCoarse);
                hold on
                contour(app.Visualization,XIFine,YIFine,ZIFine);
            end
        
            xlabel(app.Visualization,XLabel);
            ylabel(app.Visualization,YLabel);
        
            line(app.Visualization,[app.MinX.Value app.MinX.Value],[app.MinY.Value app.MaxY.Value],[MinDispl MinDispl]); hold on
            line(app.Visualization,[app.MinX.Value app.MinX.Value],[app.MaxY.Value app.MaxY.Value],[MinDispl MaxDispl]);
            line(app.Visualization,[app.MinX.Value app.MinX.Value],[app.MaxY.Value app.MinY.Value],[MaxDispl MaxDispl]);
            line(app.Visualization,[app.MinX.Value app.MinX.Value],[app.MinY.Value app.MinY.Value],[MaxDispl MinDispl]);
        
            line(app.Visualization,[app.MaxX.Value app.MaxX.Value],[app.MinY.Value app.MaxY.Value],[MinDispl MinDispl]); hold on
            line(app.Visualization,[app.MaxX.Value app.MaxX.Value],[app.MaxY.Value app.MaxY.Value],[MinDispl MaxDispl]);
            line(app.Visualization,[app.MaxX.Value app.MaxX.Value],[app.MaxY.Value app.MinY.Value],[MaxDispl MaxDispl]);
            line(app.Visualization,[app.MaxX.Value app.MaxX.Value],[app.MinY.Value app.MinY.Value],[MaxDispl MinDispl]);
        
            line(app.Visualization,[app.MinX.Value app.MaxX.Value],[app.MaxY.Value app.MaxY.Value],[MinDispl MinDispl]); hold on
            line(app.Visualization,[app.MinX.Value app.MaxX.Value],[app.MinY.Value app.MinY.Value],[MaxDispl MaxDispl]);
            line(app.Visualization,[app.MinX.Value app.MaxX.Value],[app.MaxY.Value app.MaxY.Value],[MaxDispl MaxDispl]);
            line(app.Visualization,[app.MinX.Value app.MaxX.Value],[app.MinY.Value app.MinY.Value],[MinDispl MinDispl]);
        
            % Show selected markers
            SelectedMarkers=find(app.ValidX(:,app.CurrentImage.Value)>app.MinX.Value & app.ValidX(:,app.CurrentImage.Value)<app.MaxX.Value...
                                 & app.ValidY(:,app.CurrentImage.Value)>app.MinY.Value & app.ValidY(:,app.CurrentImage.Value)<app.MaxY.Value);
        
            % Data points
            if app.DataPointsButton.Value == 1
                plot3(app.Visualization,app.ValidX(SelectedMarkers,app.CurrentImage.Value),app.ValidY(SelectedMarkers,app.CurrentImage.Value),Displ(SelectedMarkers,app.CurrentImage.Value),'.g');
            end
        
            % YZ plane
            if app.YZPlaneButton.Value == 1
                plot3(app.Visualization,app.ValidX(SelectedMarkers,app.CurrentImage.Value),BackYPlanePlot(SelectedMarkers,app.CurrentImage.Value)-2,Displ(SelectedMarkers,app.CurrentImage.Value),'.b');
                hold on
            end
        
            % XZ plane
            if app.XZPlaneButton.Value == 1
                plot3(app.Visualization,BackXPlanePlot(SelectedMarkers,app.CurrentImage.Value)-2,app.ValidY(SelectedMarkers,app.CurrentImage.Value),Displ(SelectedMarkers,app.CurrentImage.Value),'.b');
                hold on
            end
        
            % Average strain
            Beta=[0 0];
            if app.AverageStrainButton.Value == 1
                XData=app.ValidX(SelectedMarkers,app.CurrentImage.Value);
                YData=Displ(SelectedMarkers,app.CurrentImage.Value);
                [Beta]=lsqcurvefit(@Line,Beta,XData,YData);
                YDataPredicted=Line(Beta,XData);
                plot3(app.Visualization,XData,BackYPlanePlot(SelectedMarkers,app.CurrentImage.Value)-6,YDataPredicted,'.r');
                hold on
            end
        
            zlabel(app.Visualization,'displacement [pixel]')
            hold off
            Titles={'Displacement in x-direction versus x-y-position','Displacement in y-direction versus x-y-position'};
            ImageString=sprintf(' (Image #: %1g)',app.CurrentImage.Value);
            BetaString=sprintf(' (Strain x: %1g)',Beta(1,1));
        
            % Switch orientation
            if app.SwitchOrientationButton.Value == 0
                if app.AverageStrainButton.Value == 1
                    title(app.Visualization,[Titles{1,1},ImageString,BetaString]);
                else
                    title(app.Visualization,[Titles{1,1},ImageString]);
                end
            else
                if app.AverageStrainButton.Value == 1
                    title(app.Visualization,[Titles{1,2},ImageString,BetaString]);
                else
                    title(app.Visualization,[Titles{1,2},ImageString]);
                end
            end
        
            % Switch orientation
            if app.SwitchOrientationButton.Value == 0
                if (MinDisplX < MaxDisplX)
                    axis([app.MinX.Value app.MaxX.Value app.MinY.Value app.MaxY.Value MinDisplX MaxDisplX]);
                end
            else
                if (MinDisplY<MaxDisplY)
                    axis([app.MinX.Value app.MaxX.Value app.MinY.Value app.MaxY.Value MinDisplY MaxDisplY]);
                end
            end
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DisplacementAnalysis_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.DisplacementAnalysisFigure, 'onscreen')

            if length(varargin)>=5 && ~isempty(varargin{1,1}) && ~isempty(varargin{1,2}) % call with 2 or more non-empty parameters
                app.ValidX = varargin{1,1};
                app.ValidY = varargin{1,2};
                app.StdX = varargin{1,3};
                app.StdY = varargin{1,4};
                app.CorrCoef = varargin{1,5};
                cd(varargin{1,6})
            elseif isempty(varargin)
                OpenCoordinatesFcn(app)
            end
            
            if ~isempty(app.ValidX) && ~isempty(app.ValidY)
                EnableMenuEntries(app);
                [app.defaultMinX,app.defaultMaxX,app.defaultMinY,app.defaultMaxY] = GetMinMax(app, app.ValidX, app.ValidY);
                ResetMinMax(app)
                Visualize3D(app)
            end
        end

        % Menu selected function: CleanMarkersMenu
        function Clean_Callback(app, event)
            [app.ValidX,app.ValidY,app.StdX,app.StdY,app.CorrCoef,~] = CleanMarkers(app.ValidX,app.ValidY,app.StdX,app.StdY,app.CorrCoef,...
                app.Directions(app.SwitchOrientationButton.Value+1),'non-silent',app.LogFileName,app.MetaData);
            ResetLimitsPushButton_Callback(app,event);
        end

        % Value changed function: CurrentImage
        function CurrentImage_Callback(app, event)
            if ~isreal(event.Value) || mod(event.Value,1) ~= 0
                app.CurrentImage.Value = event.PreviousValue;
            else
                if app.MaxImage.Value > event.Value && event.Value > app.MinImage.Value
                    app.CurrentImage.Value = event.Value;
                    app.ImageSlider.Value = event.Value;
                    Visualize3D(app);
                else
                    app.CurrentImage.Value = event.PreviousValue;
                end
            end
        end

        % Value changed function: ImageSlider
        function ImageSlider_Callback(app, event)
            app.ImageSlider.Value = round(event.Value);
            app.CurrentImage.Value = app.ImageSlider.Value;
            Visualize3D(app);
        end

        % Value changed function: MinImage
        function MinImage_Callback(app, event)
            if isempty(event.Value) || isnan(event.Value) || mod(event.Value,1) ~= 0
                app.MinImage.Value = event.PreviousValue;
            else
                if app.defaultMinImage <= event.Value && event.Value <= app.CurrentImage.Value
                    app.MinImage.Value = event.Value;    
                else
                    app.MinImage.Value = event.PreviousValue;
                end
            end
        end

        % Value changed function: MaxImage
        function MaxImage_Callback(app, event)
            if isempty(event.Value) || isnan(event.Value) || mod(event.Value,1) ~= 0
                app.MaxImage.Value = event.PreviousValue;
            else
                if app.defaultMaxImage >= event.Value && event.Value >= app.CurrentImage.Value
                    app.MaxImage.Value = event.Value;
                else
                    app.MaxImage.Value = event.PreviousValue;
                end
            end
        end

        % Value changed function: MinX
        function MinX_Callback(app, event)
            if isnan(event.Value) || ~isreal(event.Value) || isempty(event.Value)
                app.MinX.Value = event.PreviousValue;
            else
                if app.MaxX.Value > event.Value && event.Value > app.defaultMinX
                    app.MinX.Value = event.Value;
                    Visualize3D(app);
                else
                    app.MinX.Value = event.PreviousValue;
                end
            end
        end

        % Value changed function: MaxX
        function MaxX_Callback(app, event)
            if isnan(event.Value) || ~isreal(event.Value) || isempty(event.Value)
                app.MaxX.Value = event.PreviousValue;
            else
                if app.defaultMaxX > event.Value && event.Value > app.MinX.Value
                    app.MaxX.Value = event.Value;
                    Visualize3D(app);
                else
                    app.MaxX.Value = event.PreviousValue;
                end
            end
        end

        % Value changed function: MinY
        function MinY_Callback(app, event)
            if isnan(event.Value) || ~isreal(event.Value) || isempty(event.Value)
                app.MinY.Value = event.PreviousValue;
            else
                if app.MaxY.Value > event.Value && event.Value > app.defaultMinY
                    app.MinY.Value = event.Value;
                    Visualize3D(app);
                else
                    app.MinY.Value = event.PreviousValue;
                end
            end
        end

        % Value changed function: MaxY
        function MaxY_Callback(app, event)
            if isnan(event.Value) || ~isreal(event.Value) || isempty(event.Value)
                app.MaxY.Value = event.PreviousValue;
            else
                if app.defaultMaxY > event.Value && event.Value > app.MinY.Value
                    app.MaxY.Value = event.Value;
                    Visualize3D(app);
                else
                    app.MaxY.Value = event.PreviousValue;
                end
            end
        end

        % Menu selected function: OpenCoordinatesMenu
        function OpenCoordinates_Callback(app, event)
            OpenCoordinatesFcn(app);
            if isempty(app.ValidX) || isempty(app.ValidY)
                return
            end
            app.ValidXBackup=app.ValidX;
            app.ValidYBackup=app.ValidY;
            EnableMenuEntries(app);
            [app.defaultMinX,app.defaultMaxX,app.defaultMinY,app.defaultMaxY] = GetMinMax(app, app.ValidX, app.ValidY);
            ResetMinMax(app);
            Visualize3D(app);
        end

        % Button pushed function: ResetLimitsPushButton
        function ResetLimitsPushButton_Callback(app, event)
            ResetMinMax(app);
            Visualize3D(app);
        end

        % Menu selected function: SaveCoordinatesMenu
        function SaveCoordinates_Callback(app, event)
            SaveCoordinates(app, app.ValidX, app.ValidY, app.StdX, app.StdY, app.CorrCoef);
            app.ValidXBackup = app.ValidX;
            app.ValidYBackup = app.ValidY;
        end

        % Menu selected function: Averagestrain1DMenu
        function StrainAnalysisMenu_Callback(app, event)
            % disp(event.Source.Text)
            StrainAnalysis(app.ValidX,app.ValidY,app.StdX,app.StdY,app.Directions(app.SwitchOrientationButton.Value+1),'gui','',event.Source.Text);
        end

        % Callback function: AverageStrainButton, DataPointsButton, 
        % ...and 6 other components
        function Visualization_Callback(app, event)
            Visualize3D(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DisplacementAnalysisFigure = uifigure('Visible', 'off');
            colormap(app.DisplacementAnalysisFigure, 'parula');
            app.DisplacementAnalysisFigure.Name = 'Displacement Analysis';
            app.DisplacementAnalysisFigure.Tag = 'DisplacementAnalysisFigure';
            app.FileMenu = uimenu(app.DisplacementAnalysisFigure);
            app.FileMenu.Text = 'File';
            app.FileMenu.Tag = 'File';
            app.OpenCoordinatesMenu = uimenu(app.FileMenu);
            app.OpenCoordinatesMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenCoordinates_Callback, true);
            app.OpenCoordinatesMenu.Text = 'Open Coordinates';
            app.OpenCoordinatesMenu.Tag = 'OpenCoordinates';
            app.SaveCoordinatesMenu = uimenu(app.FileMenu);
            app.SaveCoordinatesMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCoordinates_Callback, true);
            app.SaveCoordinatesMenu.Enable = 'off';
            app.SaveCoordinatesMenu.Text = 'Save Coordinates';
            app.SaveCoordinatesMenu.Tag = 'SaveCoordinates';
            app.CleanMarkersMenu = uimenu(app.DisplacementAnalysisFigure);
            app.CleanMarkersMenu.MenuSelectedFcn = createCallbackFcn(app, @Clean_Callback, true);
            app.CleanMarkersMenu.Enable = 'off';
            app.CleanMarkersMenu.Text = 'Clean Markers';
            app.CleanMarkersMenu.Tag = 'Markers';
            app.Averagestrain1DMenu = uimenu(app.DisplacementAnalysisFigure);
            app.Averagestrain1DMenu.MenuSelectedFcn = createCallbackFcn(app, @StrainAnalysisMenu_Callback, true);
            app.Averagestrain1DMenu.Enable = 'off';
            app.Averagestrain1DMenu.Text = 'Average strain 1D';
            app.Averagestrain1DMenu.Tag = 'StrainAnalysis';
            app.Visualization = uiaxes(app.DisplacementAnalysisFigure);
            app.Visualization.Tag = 'Visualization';
            app.ThreeDVisualizationPanel = uibuttongroup(app.DisplacementAnalysisFigure);
            app.ThreeDVisualizationPanel.SelectionChangedFcn = createCallbackFcn(app, @Visualization_Callback, true);
            app.ThreeDVisualizationPanel.Enable = 'off';
            app.ThreeDVisualizationPanel.Title = '3D Visualization';
            app.AverageStrainButton = uicheckbox(app.ThreeDVisualizationPanel);
            app.AverageStrainButton.ValueChangedFcn = createCallbackFcn(app, @Visualization_Callback, true);
            app.AverageStrainButton.Enable = 'off';
            app.AverageStrainButton.Text = 'Average strain';
            app.SwitchOrientationButton = uicheckbox(app.ThreeDVisualizationPanel);
            app.SwitchOrientationButton.ValueChangedFcn = createCallbackFcn(app, @Visualization_Callback, true);
            app.SwitchOrientationButton.Enable = 'off';
            app.SwitchOrientationButton.Text = 'Switch orientation';
            app.VectorButton = uicheckbox(app.ThreeDVisualizationPanel);
            app.VectorButton.ValueChangedFcn = createCallbackFcn(app, @Visualization_Callback, true);
            app.VectorButton.Enable = 'off';
            app.VectorButton.Text = 'Vector';
            app.YZPlaneButton = uicheckbox(app.ThreeDVisualizationPanel);
            app.YZPlaneButton.ValueChangedFcn = createCallbackFcn(app, @Visualization_Callback, true);
            app.YZPlaneButton.Enable = 'off';
            app.YZPlaneButton.Text = 'YZ Plane';
            app.YZPlaneButton.Value = true;
            app.XZPlaneButton = uicheckbox(app.ThreeDVisualizationPanel);
            app.XZPlaneButton.ValueChangedFcn = createCallbackFcn(app, @Visualization_Callback, true);
            app.XZPlaneButton.Enable = 'off';
            app.XZPlaneButton.Text = 'XZ Plane';
            app.XZPlaneButton.Value = true;
            app.DataPointsButton = uicheckbox(app.ThreeDVisualizationPanel);
            app.DataPointsButton.ValueChangedFcn = createCallbackFcn(app, @Visualization_Callback, true);
            app.DataPointsButton.Enable = 'off';
            app.DataPointsButton.Text = 'Data points';
            app.DataPointsButton.Value = true;
            app.MeshButton = uicheckbox(app.ThreeDVisualizationPanel);
            app.MeshButton.ValueChangedFcn = createCallbackFcn(app, @Visualization_Callback, true);
            app.MeshButton.Enable = 'off';
            app.MeshButton.Text = 'Mesh';
            app.MeshButton.Value = true;
            app.ImageControlPanel = uipanel(app.DisplacementAnalysisFigure);
            app.ImageControlPanel.Enable = 'off';
            app.ImageControlPanel.Title = 'Image Control';
            app.ImageControlPanel.Tag = 'ImageControlPanel';
            app.MaxImage = uieditfield(app.ImageControlPanel, 'numeric');
            app.MaxImage.Limits = [0 Inf];
            app.MaxImage.AllowEmpty = 'on';
            app.MaxImage.ValueChangedFcn = createCallbackFcn(app, @MaxImage_Callback, true);
            app.MaxImage.Enable = 'off';
            app.MaxImage.Placeholder = 'Last image';
            app.MaxImage.Value = [];
            app.CurrentImage = uieditfield(app.ImageControlPanel, 'numeric');
            app.CurrentImage.Limits = [0 Inf];
            app.CurrentImage.AllowEmpty = 'on';
            app.CurrentImage.ValueChangedFcn = createCallbackFcn(app, @CurrentImage_Callback, true);
            app.CurrentImage.Enable = 'off';
            app.CurrentImage.Placeholder = 'Current Image';
            app.CurrentImage.Value = [];
            app.MinImage = uieditfield(app.ImageControlPanel, 'numeric');
            app.MinImage.Limits = [0 Inf];
            app.MinImage.AllowEmpty = 'on';
            app.MinImage.ValueChangedFcn = createCallbackFcn(app, @MinImage_Callback, true);
            app.MinImage.Enable = 'off';
            app.MinImage.Placeholder = 'First image';
            app.MinImage.Value = [];
            app.ImageSlider = uislider(app.ImageControlPanel);
            app.ImageSlider.Limits = [0 1];
            app.ImageSlider.MajorTicks = [];
            app.ImageSlider.MajorTickLabels = {};
            app.ImageSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSlider_Callback, true);
            app.ImageSlider.MinorTicks = [0 0.005 0.01 0.015 0.02 0.025 0.03 0.035 0.04 0.045 0.05 0.055 0.06 0.065 0.07 0.075 0.08 0.085 0.09 0.095 0.1 0.105 0.11 0.115 0.12 0.125 0.13 0.135 0.14 0.145];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
            app.ImageSlider.Tag = 'ImageSlider';
            app.ImageSlider.Enable = 'off';
            app.MinEditFieldLabel = uilabel(app.ImageControlPanel);
            app.MinEditFieldLabel.Enable = 'off';
            app.MinEditFieldLabel.Text = 'Min #';
            app.ImageEditFieldLabel_2 = uilabel(app.ImageControlPanel);
            app.ImageEditFieldLabel_2.Enable = 'off';
            app.ImageEditFieldLabel_2.Text = 'Image #';
            app.MaxEditFieldLabel = uilabel(app.ImageControlPanel);
            app.MaxEditFieldLabel.Enable = 'off';
            app.MaxEditFieldLabel.Text = 'Max #';
            app.LateralControlPanel = uipanel(app.DisplacementAnalysisFigure);
            app.LateralControlPanel.Enable = 'off';
            app.LateralControlPanel.Title = 'Lateral Control';
            app.LateralControlPanel.Tag = 'LateralControlPanel';
            app.MaxY = uieditfield(app.LateralControlPanel, 'numeric');
            app.MaxY.Limits = [0 Inf];
            app.MaxY.AllowEmpty = 'on';
            app.MaxY.ValueChangedFcn = createCallbackFcn(app, @MaxY_Callback, true);
            app.MaxY.Enable = 'off';
            app.MaxY.Placeholder = 'MaxY';
            app.MaxY.Value = [];
            app.MinxaxisEditFieldLabel_2 = uilabel(app.LateralControlPanel);
            app.MinxaxisEditFieldLabel_2.Enable = 'off';
            app.MinxaxisEditFieldLabel_2.Text = 'Min. x-axis';
            app.MaxxaxisLabel = uilabel(app.LateralControlPanel);
            app.MaxxaxisLabel.Enable = 'off';
            app.MaxxaxisLabel.Text = 'Max. x-axis';
            app.MinyaxisEditFieldLabel = uilabel(app.LateralControlPanel);
            app.MinyaxisEditFieldLabel.Enable = 'off';
            app.MinyaxisEditFieldLabel.Text = 'Min. y-axis';
            app.MinY = uieditfield(app.LateralControlPanel, 'numeric');
            app.MinY.Limits = [0 Inf];
            app.MinY.AllowEmpty = 'on';
            app.MinY.ValueChangedFcn = createCallbackFcn(app, @MinY_Callback, true);
            app.MinY.Enable = 'off';
            app.MinY.Placeholder = 'MinY';
            app.MinY.Value = [];
            app.MaxyaxisEditFieldLabel = uilabel(app.LateralControlPanel);
            app.MaxyaxisEditFieldLabel.Enable = 'off';
            app.MaxyaxisEditFieldLabel.Text = 'Max. y-axis';
            app.MaxX = uieditfield(app.LateralControlPanel, 'numeric');
            app.MaxX.Limits = [0 Inf];
            app.MaxX.AllowEmpty = 'on';
            app.MaxX.ValueChangedFcn = createCallbackFcn(app, @MaxX_Callback, true);
            app.MaxX.Enable = 'off';
            app.MaxX.Placeholder = 'MaxX';
            app.MaxX.Value = [];
            app.MinX = uieditfield(app.LateralControlPanel, 'numeric');
            app.MinX.Limits = [0 Inf];
            app.MinX.AllowEmpty = 'on';
            app.MinX.ValueChangedFcn = createCallbackFcn(app, @MinX_Callback, true);
            app.MinX.Enable = 'off';
            app.MinX.Placeholder = 'MinX';
            app.MinX.Value = [];
            app.ResetLimitsPushButton = uibutton(app.LateralControlPanel, 'push');
            app.ResetLimitsPushButton.ButtonPushedFcn = createCallbackFcn(app, @ResetLimitsPushButton_Callback, true);
            app.ResetLimitsPushButton.Tag = 'ResetLimitsPushButton';
            app.ResetLimitsPushButton.Enable = 'off';
            app.ResetLimitsPushButton.Text = 'Reset Limits';
            % Show the figure after all components are created
            app.DisplacementAnalysisFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplacementAnalysis_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DisplacementAnalysisFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)DisplacementAnalysis_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DisplacementAnalysisFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DisplacementAnalysisFigure)
        end
    end
end