classdef DP_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DPAppFigure                     matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        FijiPathMenu                    matlab.ui.container.Menu
        LoadConfigFileMenu              matlab.ui.container.Menu
        SaveConfigFileMenu              matlab.ui.container.Menu
        GraphPreviewMenu                matlab.ui.container.Menu
        WorkfolderLabel                 matlab.ui.control.Label
        SavingSwitch                    matlab.ui.control.Switch
        SavingSwitchLabel               matlab.ui.control.Label
        Title                           matlab.ui.control.Label
        CloseAllButton                  matlab.ui.control.Button
        WorkFolderEditField             matlab.ui.control.TextArea
        MsgConsole                      matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        DataSelectionTab                matlab.ui.container.Tab
        DataSelectionButton             matlab.ui.control.Button
        ImageProcessingTab              matlab.ui.container.Tab
        ProcessStackButton              matlab.ui.control.Button
        ImageProcessingParametersButton  matlab.ui.control.Button
        DICTab                          matlab.ui.container.Tab
        GenerateFileListButton          matlab.ui.control.Button
        FileListEditButton              matlab.ui.control.Button
        ProcessCorrelationsButton       matlab.ui.control.Button
        ProcessCorrelationsParametersButton  matlab.ui.control.Button
        DisplacementAnalysisButton      matlab.ui.control.Button
        DepthProfilingTab               matlab.ui.container.Tab
        DP1SeriesButton                 matlab.ui.control.Button
        DPParametersButton              matlab.ui.control.Button
        AveragingTab                    matlab.ui.container.Tab
        Averaging1PillarButton          matlab.ui.control.Button
        AveragingParametersButton       matlab.ui.control.Button
        StringSampleButton              matlab.ui.control.Button
        StringParametersButton          matlab.ui.control.Button
        SamplesComparisonTab            matlab.ui.container.Tab
        CompareSamplesButton            matlab.ui.control.Button
        CompareSamplesParametersButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        FijiPath = "";
        
        % DIC
        filenameList = char(strings(1,1));
        
        validX = [];
        validY = [];

        stdX = [];
        stdY = [];

        corrCoef = [];

        gridX = [];
        gridY = [];

        % Paths management
        appPath = '';

        % Saving
        savingStatus = false;

        % Apps
        DataSelectionDialogApp
        ImageProcessingParametersDialogApp
        FilelistParametersDialogApp
        ProcessCorrelationsParametersDialogApp
        DPParametersDialogApp
        AveragingParametersDialogApp
        StringParametersDialogApp
        CompareSamplesParametersDialogApp
    end
    
    properties (Access = public)
        %% Graph preview menu
        xAxisData = 'Normalized depth';
        xAxisLowLimit = -inf;
        xAxisHighLimit = inf;
        yAxisLowLimit = -inf;
        yAxisHighLimit = inf;

        %% Workfolder
        workFolder = '';

        %% Data selection
        samplePath = '';
        pillarPath = '';
        seriesPath = '';
        scanDirection = 0;
        
        %% Image processing parameters
        cropOption = true;
        imageWidth = 1024;
        imageHeight = 884;

        imgRotation = 0;
        filter1 = 'Image registration (alignment)';
        filter2 = 'Intensity averaging';
        nbImagesStep = 5;

        %% File list parameters
        automaticFilelist = true;

        root = '';
        firstImgNb = 0;
        nbImages = 20;
        nbDigits = 3;
        imgFormat = 'tif';

        %% Process correlations parameters
        corrSize = 15;
        redFactor = 15;
        processMode = 'Single';
        refImage = 'First';
        dispType = 'Large displacements (vectorial)';

        %% DP parameters
        % Data selection tab
        zeroStrainFile
        zeroStrainPath
        ninetyStrainFile
        ninetyStrainPath
        fourtyFiveStrainFile
        fourtyFiveStrainPath
        
        stressStateAssumption = 'Equibiaxial';

        % Parameters tab
        % Geometrical properties
        pillarDiameter = 10; % [µm]
        stepSize = .25; % [µm]

        % Material properties
        elasticModulus = 120; % [GPa]
        poissonRatio = .36;

        % Normalized depth limits
        normalizedDepthLowLimit = 0;
        normalizedDepthHighLimit = .2;

        % Smoothing parameters
        pointsSpan = 6;
        polyDegree = 3;

        % DIC parameters
        pillarRedFactor = 0.8;

        %% Additional functions
        % Averaging plots
        displayIndividualPlots = false;

        % Stringing
        stringingMode = "Combined";

        % Comparison
        sample1Path
        sample2Path
        plot1Name
        plot2Name
        fullComparisonOption = true;
        comparisonSavingPath
        comparisonFileName
    end
    
    methods (Access = private)
        
        function loadFilesSeries(app)
            try
                app.validX = importdata(fullfile(app.workFolder,'validx.dat'),'\t');
                app.validY = importdata(fullfile(app.workFolder,'validy.dat'),'\t');
                app.stdX = importdata(fullfile(app.workFolder,'stdx.dat'),'\t');
                app.stdY = importdata(fullfile(app.workFolder,'stdy.dat'),'\t');
                app.corrCoef = importdata(fullfile(app.workFolder,'corrcoef.dat'),'\t');
            catch
            end
        end
        
        function disableAll(app)
            app.SavingSwitch.Enable = "off";
            app.CloseAllButton.Enable = "off";
            app.WorkFolderEditField.Enable = "off";
            
            % Select data tab
            app.DataSelectionButton.Enable = "off";

            % Image processing tab
            app.ProcessStackButton.Enable = "off";
            app.ImageProcessingParametersButton.Enable = "off";
            
            % DIC tab
            app.GenerateFileListButton.Enable = "off";
            app.FileListEditButton.Enable = "off";
            app.ProcessCorrelationsButton.Enable = "off";
            app.ProcessCorrelationsParametersButton.Enable = "off";
            app.DisplacementAnalysisButton.Enable = "off";

            % DP tab
            app.DP1SeriesButton.Enable = "off";
            app.DPParametersButton.Enable = "off";

            % Averaging tab
            app.Averaging1PillarButton.Enable = "off";
            app.AveragingParametersButton.Enable = "off";
            app.StringSampleButton.Enable = "off";
            app.StringParametersButton.Enable = "off";

            % Samples comparison tab
            app.CompareSamplesButton.Enable = "off";
            app.CompareSamplesParametersButton.Enable = "off";
        end
    end
    
    methods (Access = public)
        
        function enableAll(app)
            app.SavingSwitch.Enable = "on";
            app.CloseAllButton.Enable = "on";
            app.WorkFolderEditField.Enable = "on";
            
            % Select data tab
            app.DataSelectionButton.Enable = "on";

            if ~isempty(app.workFolder)
                if ~isempty(app.FijiPath)
                    % Image processing tab
                    app.ProcessStackButton.Enable = "on";
                    app.ImageProcessingParametersButton.Enable = "on";
                end
            
                % DIC tab
                app.GenerateFileListButton.Enable = "on";
                app.FileListEditButton.Enable = "on";
                app.ProcessCorrelationsButton.Enable = "on";
                app.ProcessCorrelationsParametersButton.Enable = "on";
                app.DisplacementAnalysisButton.Enable = "on";
        
                % DP tab
                app.DP1SeriesButton.Enable = "on";
                app.DPParametersButton.Enable = "on";
            end

            % Averaging tab
            if ~isempty(app.pillarPath)
                app.Averaging1PillarButton.Enable = "on";
                app.AveragingParametersButton.Enable = "on";
            end

            if ~isempty(app.samplePath)
                app.StringSampleButton.Enable = "on";
                app.StringParametersButton.Enable = "on";
            end
            
            % Samples comparison tab
            app.CompareSamplesButton.Enable = "on";
            app.CompareSamplesParametersButton.Enable = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AppStartupFcn(app)
            disableAll(app)

            % add the DIC app path to use the same function
            [app.appPath,~] = fileparts(mfilename("fullpath"));
            addpath(fullfile(app.appPath,'DIC_app_designer-kento','code'))

            % Warn user if no Fiji path was detected
            try
                app.FijiPath = load(fullfile(app.appPath,"FijiPath")).FijiPathUser;
                app.MsgConsole.Value = sprintf("Fiji loaded from %s", app.FijiPath);
            catch ME
                disp(ME.message)
                uialert(app.DPAppFigure,"Add a path to the Fiji directory if you want to use image processing functions.","Fiji not found")
            end
            
            if isvalid(app)
                enableAll(app)
            end
        end

        % Menu selected function: FijiPathMenu
        function FijiPathCallback(app, event)
            if isempty(app.FijiPath) | strcmp(app.FijiPath,"")
                app.FijiPath = uigetdir(app.appPath,'Select Fiji.app.');
            else
                app.FijiPath = uigetdir(app.FijiPath,'Select Fiji.app.');
            end

            if isempty(app.FijiPath) | strcmp(app.FijiPath,"")
                return
            end

            if ~isfile(fullfile(app.FijiPath,'scripts','Miji.m'))
                uialert(app.DPAppFigure,"Miji not found in scripts directory inside Fiji. Verify the installation.","Miji not found")
                return
            end

            FijiPathUser = app.FijiPath;
            save(fullfile(app.appPath,"FijiPath"),"FijiPathUser")

            % Confirm that Fiji was loaded
            app.MsgConsole.Value = sprintf("Fiji loaded from %s", app.FijiPath);
        end

        % Menu selected function: LoadConfigFileMenu
        function LoadConfigFileMenuCallback(app, event)
            % Prompt user to select file
            [configFile,configPath] = uigetfile('*.mat','Select the configuration file.');
            if configFile == 0 | isempty(configFile)
                app.MsgConsole.Value = 'No file selected.';
            else
                configData = load(fullfile(configPath,configFile)).configData;
            end

            % Update all relevant app properties with config file properties
            allFields = fieldnames(configData);
            for i = 1:numel(allFields)
                property = allFields{i};
                try
                    app.(property) = configData.(property);
                catch ME
                    app.MsgConsole.Value = ME.message;
                end
            end

            % Update UI with values from config file
            %% No tab
            if app.savingStatus == false
                app.SavingSwitch.Value = "Off";
            else
                app.SavingSwitch.Value = "On";
            end
            app.WorkFolderEditField.Value = app.workFolder;

            enableAll(app)
        end

        % Menu selected function: SaveConfigFileMenu
        function SaveConfigFileCallback(app, event)
            % Create config file and add all properties into it
            configData = struct();

            configData.savingStatus = app.savingStatus;
            configData.workFolder = app.workFolder;

            %% Data selection tab
            configData.samplePath = app.samplePath;
            configData.pillarPath = app.pillarPath;
            configData.seriesPath = app.seriesPath;
            configData.scanDirection = app.scanDirection;

            %% Image processing tab
            configData.cropOption = app.cropOption;
            configData.imageWidth = app.imageWidth;
            configData.imageHeight = app.imageHeight;
            configData.nbImagesStep = app.nbImagesStep;
            configData.filter1 = app.filter1;
            configData.filter2 = app.filter2;
            
            %% DIC tab
            % File list parameters
            configData.automaticFilelist = app.automaticFilelist;
            configData.root = app.root;
            configData.firstImgNb = app.firstImgNb;
            configData.nbImages = app.nbImages;
            configData.nbDigits = app.nbDigits;
            configData.imgFormat = app.imgFormat;
            
            % Process correlations parameters
            configData.corrSize = app.corrSize;
            configData.redFactor = app.redFactor;
            configData.processMode = app.processMode;
            configData.refImage = app.refImage;
            configData.dispType = app.dispType;

            %% DP tab
            % Data selection
            configData.stressStateAssumption = app.stressStateAssumption;
            configData.zeroStrainFile = app.zeroStrainFile;
            configData.zeroStrainPath = app.zeroStrainPath;
            configData.ninetyStrainFile = app.ninetyStrainFile;
            configData.ninetyStrainPath = app.ninetyStrainPath;
            configData.fourtyFiveStrainFile = app.fourtyFiveStrainFile;
            configData.fourtyFiveStrainPath = app.fourtyFiveStrainPath;

            % Parameters
            % Geometrical properties
            configData.pillarDiameter = app.pillarDiameter;
            configData.regularStepSizeOption = app.irregularStepSizeOption;
            configData.stepSize = app.stepSize;
            configData.stepSizeFile = app.stepSizeFile;
            configData.stepSizePath = app.stepSizePath;
            
            % DIC parameters
            configData.pillarRedFactor = app.pillarRedFactor;
            
            % Smoothing parameters
            configData.pointsSpan = app.pointsSpan;
            configData.polyDegree = app.polyDegree;

            % Material properties
            configData.elasticModulus = app.elasticModulus;
            configData.poissonRatio = app.poissonRatio;

            % Plotting options
            configData.xAxisData = app.xAxisData;
            configData.xAxisLowLimit = app.xAxisLowLimit;
            configData.xAxisHighLimit = app.xAxisHighLimit;
            configData.yAxisLowLimit = app.yAxisLowLimit;
            configData.yAxisHighLimit = app.yAxisHighLimit;
          
            % Averaging plots
            configData.displayIndividualPlots = app.displayIndividualPlots;

            % Stringing
            configData.stringingMode = app.stringingMode;

            % Comparison
            configData.sample1Path = app.sample1Path;
            configData.sample2Path = app.sample2Path;
            configData.sample1Name = app.plot1Name;
            configData.sample2Name = app.plot2Name;
            configData.fullComparisonOption = app.fullComparisonOption;
            configData.comparisonSavingPath = app.comparisonSavingPath;
            configData.comparisonFileName = app.comparisonFileName;

            %% Save and print success in console
            save(fullfile(app.workFolder,"config.mat"),"configData")

            app.MsgConsole.Value = "Configuration file saved in work folder.";
        end

        % Menu selected function: GraphPreviewMenu
        function GraphPreviewCallback(app, event)
            GraphPreview(app,app.xAxisData,app.xAxisLowLimit,app.xAxisHighLimit,app.yAxisLowLimit,app.yAxisHighLimit)
        end

        % Value changed function: SavingSwitch
        function SavingSwitchCallback(app, event)
            if app.SavingSwitch.Value == "On"
                app.savingStatus = true;
            else
                app.savingStatus = false;
            end
        end

        % Button pushed function: CloseAllButton
        function CloseAllCallback(app, event)
            close all
        end

        % Button pushed function: DataSelectionButton
        function DataSelectionCallback(app, event)
            disableAll(app)

            app.DataSelectionDialogApp = DataSelectionApp(app,app.appPath,app.workFolder,app.seriesPath,app.pillarPath,app.samplePath,app.scanDirection);

            waitfor(app.DataSelectionDialogApp)
            
            if ~isempty(app.workFolder)
                app.WorkFolderEditField.Value = app.workFolder;
            end

            % Automatically detect strain file
            try
                app.zeroStrainPath = fullfile(app.seriesPath,'processed','0');
                app.zeroStrainFile = 'eulerianstraintruex.dat';
            catch ME
                disp(ME.message)
            end

            % Enable relevant buttons
            if isvalid(app)
                enableAll(app)
            end
        end

        % Button pushed function: ProcessStackButton
        function ImageProcessingCallback(app, event)
            if app.filter1 == "Intensity averaging" || app.filter2 == "Intensity averaging"
                if mod(app.nbImages,app.nbImagesStep) ~= 0
                    divisionAlert = uiconfirm(app.DPAppFigure, ...
                        "The number of images selected is not divisible by the number of images taken at every milling step. Change the filter(s).", ...
                        "Step size error","Options","OK","Icon","warning");
                    waitfor(divisionAlert)
                    return
                end
            end

            pretreatment_FIB_images(app.FijiPath,app.seriesPath,app.savingStatus,app.imgFormat,app.cropOption,app.imageWidth,app.imageHeight, ...
                app.scanDirection,app.imgRotation,app.filter1,app.filter2,app.nbImagesStep);
        end

        % Button pushed function: ImageProcessingParametersButton
        function ImageProcessingParametersCallback(app, event)
            disableAll(app)

            app.ImageProcessingParametersDialogApp = ImageProcessingParameters(app,app.imgFormat,app.cropOption,app.imageWidth,app.imageHeight, ...
                app.imgRotation,app.filter1,app.filter2,app.nbImagesStep);

            waitfor(app.ImageProcessingParametersDialogApp)

            if ~isempty(app.seriesPath)
                if app.scanDirection == 0
                    app.workFolder = fullfile(app.seriesPath,'processed',num2str(app.imgRotation));
                else
                    app.workFolder = fullfile(app.seriesPath,'processed',num2str(app.scanDirection));
                end
                app.WorkFolderEditField.Value = app.workFolder;
            end

            if isvalid(app)
                enableAll(app)
            end
        end

        % Button pushed function: GenerateFileListButton
        function GenerateFilelistCallback(app, event)
            % Generate filelist depending on automatic option
            if app.automaticFilelist == false
                app.filenameList = strings(app.nbImages,1);
                formatString = sprintf('%%0%dd',app.nbDigits);
                for imageNum = 1:app.nbImages
                    imageNumFullDigits = sprintf(formatString,imageNum+app.firstImgNb-1);
                    imageName = sprintf('%s%s.%s',app.root,imageNumFullDigits,app.imgFormat);
                    app.filenameList(imageNum,1) = imageName;
                end
                app.filenameList = char(app.filenameList);
            else
                allImages = dir(fullfile(app.workFolder, ['*.' app.imgFormat]));
                app.filenameList = string({allImages.name})';
            end

            % Saving
            if app.savingStatus == true
                FileNameList = app.filenameList;
                save(fullfile(app.workFolder,'filenamelist.mat'),'FileNameList')
                app.MsgConsole.Value = sprintf('Filename list created and saved to %s.',app.workFolder);
            else
                app.MsgConsole.Value = 'Filename list not saved. Change the saving status with the switch if you want to save your data.';
            end
        end

        % Button pushed function: FileListEditButton
        function FilelistParametersCallback(app, event)
            disableAll(app)
            app.FilelistParametersDialogApp = FileListParameters(app,app.automaticFilelist,app.root,app.firstImgNb,app.nbImages,app.nbDigits, ...
                app.imgFormat);
            
            waitfor(app.FilelistParametersDialogApp)
            
            if isvalid(app)
                enableAll(app)
            end
        end

        % Button pushed function: ProcessCorrelationsButton
        function ProcessCorrelationsCallback(app, event)
            cd(app.workFolder)
            try
                filenameListFile = load(fullfile(cd,'filenamelist.mat'));
                app.filenameList = filenameListFile.FileNameList;
            catch
                msgbox('Filename list not found.')
            end
            try
                [app.validX,app.validY,app.stdX,app.stdY,app.corrCoef] = ProcessCorrelations(0, app.gridX, app.gridY, app.filenameList, app.validX, ...
                    app.validY, app.corrSize, app.processMode, app.refImage, app.dispType, app.redFactor);
            catch ME
                app.MsgConsole.Value = ME.message;
            end
        end

        % Button pushed function: ProcessCorrelationsParametersButton
        function ProcessCorrelationsParametersCallback(app, event)
            disableAll(app)
            
            app.ProcessCorrelationsParametersDialogApp = ProcessCorrelationsParameters(app,app.corrSize,app.redFactor,app.processMode,app.refImage, ...
                app.dispType);

            waitfor(app.ProcessCorrelationsParametersDialogApp)

            if isvalid(app)
                enableAll(app)
            end
        end

        % Button pushed function: DisplacementAnalysisButton
        function DisplacementAnalysisCallback(app, event)
            if strcmp(app.seriesPath,'')
                DisplacementAnalysis_App(app.validX, app.validY, app.stdX, app.stdY, app.corrCoef, '');
            else
                DisplacementAnalysis_App(app.validX, app.validY, app.stdX, app.stdY, app.corrCoef, app.workFolder)
            end
        end

        % Button pushed function: DP1SeriesButton
        function DP1SeriesCallback(app, event)
            if ~isfolder(app.seriesPath)
                msgbox('Select a path to a series first.')
                return
            end
            
            if strcmp(app.stressStateAssumption,"Equibiaxial")
                DP_equibiaxial(app.savingStatus,app.seriesPath,fullfile(app.zeroStrainPath,app.zeroStrainFile),app.scanDirection,app.pillarDiameter, ...
                    app.stepSize, app.pillarRedFactor, app.xAxisData, app.xAxisLowLimit, app.xAxisHighLimit,app.yAxisLowLimit, app.yAxisHighLimit, ...
                    app.pointsSpan, app.polyDegree, app.elasticModulus, app.poissonRatio, app.normalizedDepthLowLimit, app.normalizedDepthHighLimit)

            elseif strcmp(app.stressStateAssumption,"Non-equibiaxial")
                DP_non_equibiaxial(app.savingStatus,app.seriesPath,fullfile(app.zeroStrainPath,app.zeroStrainFile),fullfile(app.ninetyStrainPath,app.ninetyStrainFile), ...
                    app.pillarDiameter,app.stepSize,app.pillarRedFactor,app.xAxisData,app.xAxisLowLimit, app.xAxisHighLimit,app.yAxisLowLimit, ...
                    app.yAxisHighLimit, app.pointsSpan,app.polyDegree, app.elasticModulus, app.poissonRatio)
                
            else
                DP_full_field(app.savingStatus,app.seriesPath,fullfile(app.zeroStrainPath,app.zeroStrainFile),fullfile(app.ninetyStrainPath,app.ninetyStrainFile), ...
                    fullfile(app.fourtyFiveStrainPath,app.fourtyFiveStrainFile),app.pillarDiameter,app.stepSize,app.pillarRedFactor,app.xAxisData, ...
                    app.xAxisLowLimit, app.xAxisHighLimit,app.yAxisLowLimit,app.yAxisHighLimit, app.pointsSpan,app.polyDegree, app.elasticModulus, app.poissonRatio)
            end
        end

        % Button pushed function: DPParametersButton
        function DPParametersCallback(app, event)
            disableAll(app)

            app.DPParametersDialogApp = DPParameters(app,app.stressStateAssumption,app.zeroStrainFile,app.zeroStrainPath,app.ninetyStrainFile,app.ninetyStrainPath, ...
                app.fourtyFiveStrainFile,app.fourtyFiveStrainPath,app.pillarDiameter,app.stepSize,app.pillarRedFactor,app.pointsSpan,app.polyDegree, ...
                app.elasticModulus,app.poissonRatio,app.normalizedDepthLowLimit,app.normalizedDepthHighLimit);
            
            % Wait until user enters data
            waitfor(app.DPParametersDialogApp)

            % If the app is still open, enable the processing button again
            if isvalid(app)
                enableAll(app)
            end
        end

        % Button pushed function: Averaging1PillarButton
        function Averaging1PillarCallback(app, event)
            if isfolder(app.pillarPath)
                averaging_1_pillar(app.savingStatus,app.pillarPath,app.pillarDiameter, app.xAxisLowLimit,app.xAxisHighLimit, app.yAxisLowLimit, ...
                    app.yAxisHighLimit, app.displayIndividualPlots, app.scanDirection, app.normalizedDepthLowLimit, app.normalizedDepthHighLimit)
            else
                uiwait('Select a path to a pillar first.')
                return
            end
        end

        % Button pushed function: AveragingParametersButton
        function AveragingParametersCallback(app, event)
            disableAll(app)

            app.AveragingParametersDialogApp = AveragingParameters(app,app.displayIndividualPlots);

            waitfor(app.AveragingParametersDialogApp)

            if isvalid(app)
                enableAll(app)
            end
        end

        % Button pushed function: StringSampleButton
        function StringSampleCallback(app, event)
            if isfolder(app.samplePath)
                if strcmp(app.stringingMode,"Successive")
                    string_plots_together_succession(app.savingStatus, app.samplePath, app.xAxisLowLimit, app.xAxisHighLimit, app.yAxisLowLimit, ...
                        app.yAxisHighLimit)
                else
                    string_plots_together_combined(app.savingStatus, app.samplePath, app.xAxisLowLimit, app.xAxisHighLimit, app.yAxisLowLimit, ...
                        app.yAxisHighLimit)
                end
            else
                msgbox('Select a path to a sample first.')
                return
            end
        end

        % Button pushed function: StringParametersButton
        function StringParametersCallback(app, event)
            disableAll(app)

            app.StringParametersDialogApp = StringParameters(app,app.stringingMode);

            waitfor(app.StringParametersDialogApp)
            
            if isvalid(app)
                enableAll(app)
            end
        end

        % Button pushed function: CompareSamplesButton
        function CompareSamplesCallback(app, event)
            compare2Samples(app.savingStatus,app.comparisonSavingPath,app.comparisonFileName,app.fullComparisonOption,app.stringingMode, ...
                app.pillarDiameter,app.sample1Path,app.sample2Path,app.plot1Name,app.plot2Name,app.xAxisLowLimit,app.xAxisHighLimit, ...
                app.yAxisLowLimit,app.yAxisHighLimit)
        end

        % Button pushed function: CompareSamplesParametersButton
        function CompareSamplesParametersCallback(app, event)
            disableAll(app)

            app.CompareSamplesParametersDialogApp = CompareSamplesParameters(app,app.savingStatus,app.sample1Path,app.sample2Path,app.plot1Name,app.plot2Name, ...
                app.fullComparisonOption,app.stringingMode,app.pillarDiameter,app.comparisonSavingPath,app.comparisonFileName);
            
            waitfor(app.CompareSamplesParametersDialogApp)

            if isvalid(app)
                enableAll(app)
            end
        end

        % Close request function: DPAppFigure
        function CloseAppCallback(app, event)
            % Delete all apps
            delete(app.ImageProcessingParametersDialogApp)
            delete(app.FilelistParametersDialogApp)
            delete(app.ProcessCorrelationsParametersDialogApp)
            delete(app.DPParametersDialogApp)
            delete(app.AveragingParametersDialogApp)
            delete(app.StringParametersDialogApp)
            delete(app.CompareSamplesParametersDialogApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DPAppFigure = uifigure('Visible', 'off');
            app.DPAppFigure.Name = 'Depth profiling application';
            app.DPAppFigure.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-DP_app.png');
            app.DPAppFigure.CloseRequestFcn = createCallbackFcn(app, @CloseAppCallback, true);
            app.FileMenu = uimenu(app.DPAppFigure);
            app.FileMenu.Text = 'File';
            app.FijiPathMenu = uimenu(app.FileMenu);
            app.FijiPathMenu.MenuSelectedFcn = createCallbackFcn(app, @FijiPathCallback, true);
            app.FijiPathMenu.Text = 'Fiji path';
            app.LoadConfigFileMenu = uimenu(app.FileMenu);
            app.LoadConfigFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConfigFileMenuCallback, true);
            app.LoadConfigFileMenu.Text = 'Load config file';
            app.SaveConfigFileMenu = uimenu(app.FileMenu);
            app.SaveConfigFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigFileCallback, true);
            app.SaveConfigFileMenu.Text = 'Save config file';
            app.GraphPreviewMenu = uimenu(app.DPAppFigure);
            app.GraphPreviewMenu.MenuSelectedFcn = createCallbackFcn(app, @GraphPreviewCallback, true);
            app.GraphPreviewMenu.Text = 'Graph preview';
            app.TabGroup = uitabgroup(app.DPAppFigure);
            app.DataSelectionTab = uitab(app.TabGroup);
            app.DataSelectionTab.Title = 'Data selection';
            app.DataSelectionButton = uibutton(app.DataSelectionTab, 'push');
            app.DataSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @DataSelectionCallback, true);
            app.DataSelectionButton.Text = 'Data selection';
            app.ImageProcessingTab = uitab(app.TabGroup);
            app.ImageProcessingTab.Title = 'Image processing';
            app.ImageProcessingParametersButton = uibutton(app.ImageProcessingTab, 'push');
            app.ImageProcessingParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ImageProcessingParametersCallback, true);
            app.ImageProcessingParametersButton.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-edit_pen_colored.png');
            app.ImageProcessingParametersButton.Enable = 'off';
            app.ImageProcessingParametersButton.Text = '';
            app.ProcessStackButton = uibutton(app.ImageProcessingTab, 'push');
            app.ProcessStackButton.ButtonPushedFcn = createCallbackFcn(app, @ImageProcessingCallback, true);
            app.ProcessStackButton.Enable = 'off';
            app.ProcessStackButton.Text = 'Process stack';
            app.DICTab = uitab(app.TabGroup);
            app.DICTab.Title = 'Digital Image Correlation';
            app.DisplacementAnalysisButton = uibutton(app.DICTab, 'push');
            app.DisplacementAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @DisplacementAnalysisCallback, true);
            app.DisplacementAnalysisButton.Enable = 'off';
            app.DisplacementAnalysisButton.Text = '3. Displacement analysis';
            app.ProcessCorrelationsParametersButton = uibutton(app.DICTab, 'push');
            app.ProcessCorrelationsParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessCorrelationsParametersCallback, true);
            app.ProcessCorrelationsParametersButton.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-edit_pen_colored.png');
            app.ProcessCorrelationsParametersButton.Enable = 'off';
            app.ProcessCorrelationsParametersButton.Text = '';
            app.ProcessCorrelationsButton = uibutton(app.DICTab, 'push');
            app.ProcessCorrelationsButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessCorrelationsCallback, true);
            app.ProcessCorrelationsButton.Enable = 'off';
            app.ProcessCorrelationsButton.Text = '2. Process correlations';
            app.FileListEditButton = uibutton(app.DICTab, 'push');
            app.FileListEditButton.ButtonPushedFcn = createCallbackFcn(app, @FilelistParametersCallback, true);
            app.FileListEditButton.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-edit_pen_colored.png');
            app.FileListEditButton.Enable = 'off';
            app.FileListEditButton.Text = '';
            app.GenerateFileListButton = uibutton(app.DICTab, 'push');
            app.GenerateFileListButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFilelistCallback, true);
            app.GenerateFileListButton.Enable = 'off';
            app.GenerateFileListButton.Text = '1. Generate file list';
            app.DepthProfilingTab = uitab(app.TabGroup);
            app.DepthProfilingTab.Title = 'Depth profiling';
            app.DPParametersButton = uibutton(app.DepthProfilingTab, 'push');
            app.DPParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DPParametersCallback, true);
            app.DPParametersButton.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-edit_pen_colored.png');
            app.DPParametersButton.Enable = 'off';
            app.DPParametersButton.Text = '';
            app.DP1SeriesButton = uibutton(app.DepthProfilingTab, 'push');
            app.DP1SeriesButton.ButtonPushedFcn = createCallbackFcn(app, @DP1SeriesCallback, true);
            app.DP1SeriesButton.Enable = 'off';
            app.DP1SeriesButton.Tooltip = {'Takes in input a file named eulerianstraintruex.dat and a file named uncertainty-normalized_depth.mat'};
            app.DP1SeriesButton.Text = 'Depth profiling';
            app.AveragingTab = uitab(app.TabGroup);
            app.AveragingTab.Title = 'Averaging';
            app.StringParametersButton = uibutton(app.AveragingTab, 'push');
            app.StringParametersButton.ButtonPushedFcn = createCallbackFcn(app, @StringParametersCallback, true);
            app.StringParametersButton.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-edit_pen_colored.png');
            app.StringParametersButton.Enable = 'off';
            app.StringParametersButton.Text = '';
            app.StringSampleButton = uibutton(app.AveragingTab, 'push');
            app.StringSampleButton.ButtonPushedFcn = createCallbackFcn(app, @StringSampleCallback, true);
            app.StringSampleButton.Enable = 'off';
            app.StringSampleButton.Text = 'String together for one sample';
            app.AveragingParametersButton = uibutton(app.AveragingTab, 'push');
            app.AveragingParametersButton.ButtonPushedFcn = createCallbackFcn(app, @AveragingParametersCallback, true);
            app.AveragingParametersButton.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-edit_pen_colored.png');
            app.AveragingParametersButton.Enable = 'off';
            app.AveragingParametersButton.Text = '';
            app.Averaging1PillarButton = uibutton(app.AveragingTab, 'push');
            app.Averaging1PillarButton.ButtonPushedFcn = createCallbackFcn(app, @Averaging1PillarCallback, true);
            app.Averaging1PillarButton.Enable = 'off';
            app.Averaging1PillarButton.Text = 'Averaging of multiple profiles';
            app.SamplesComparisonTab = uitab(app.TabGroup);
            app.SamplesComparisonTab.Title = 'Samples comparison';
            app.CompareSamplesParametersButton = uibutton(app.SamplesComparisonTab, 'push');
            app.CompareSamplesParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSamplesParametersCallback, true);
            app.CompareSamplesParametersButton.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-edit_pen_colored.png');
            app.CompareSamplesParametersButton.Text = '';
            app.CompareSamplesButton = uibutton(app.SamplesComparisonTab, 'push');
            app.CompareSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSamplesCallback, true);
            app.CompareSamplesButton.Enable = 'off';
            app.CompareSamplesButton.Text = 'Compare';
            app.MsgConsole = uitextarea(app.DPAppFigure);
            app.MsgConsole.Editable = 'off';
            app.WorkFolderEditField = uitextarea(app.DPAppFigure);
            app.WorkFolderEditField.Tooltip = {'This is where all the post-processed images as well as all the files of the DIC analysis will be saved'};
            app.CloseAllButton = uibutton(app.DPAppFigure, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllCallback, true);
            app.CloseAllButton.Text = 'Close all windows';
            app.Title = uilabel(app.DPAppFigure);
            app.Title.Text = 'Depth profiling of residual stress';
            app.SavingSwitchLabel = uilabel(app.DPAppFigure);
            app.SavingSwitchLabel.Text = 'Saving';
            app.SavingSwitch = uiswitch(app.DPAppFigure, 'slider');
            app.SavingSwitch.ValueChangedFcn = createCallbackFcn(app, @SavingSwitchCallback, true);
            app.WorkfolderLabel = uilabel(app.DPAppFigure);
            app.WorkfolderLabel.Text = 'Work folder';
            % Show the figure after all components are created
            app.DPAppFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DP_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DPAppFigure)

                % Execute the startup function
                runStartupFcn(app, @AppStartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DPAppFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DPAppFigure)
        end
    end
end