classdef DataSelectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataSelectionUIFigure       matlab.ui.Figure
        CurrentseriesLabel          matlab.ui.control.Label
        SamplePathEditField         matlab.ui.control.EditField
        BrowseSampleButton          matlab.ui.control.Button
        PillarPathEditField         matlab.ui.control.EditField
        CurrentpillarLabel          matlab.ui.control.Label
        BrowsePillarButton          matlab.ui.control.Button
        SelectedseriesLabel         matlab.ui.control.Label
        SeriesPathEditField         matlab.ui.control.EditField
        BrowseSeriesButton          matlab.ui.control.Button
        ImagesDirectionButtonGroup  matlab.ui.container.ButtonGroup
        xButton                     matlab.ui.control.RadioButton
        yButton                     matlab.ui.control.RadioButton
        FourtyFiveButton            matlab.ui.control.RadioButton
        OKButton                    matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        samplePath
        pillarPath
        seriesPath
        imagesDirection

        workFolder

        mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, workfolder, seriesPath, pillarPath, samplePath, imagesDirection)
            [~, app.SamplePathEditField.Value] = fileparts(samplePath);
            [~, app.PillarPathEditField.Value] = fileparts(pillarPath);
            [~, app.SeriesPathEditField.Value] = fileparts(seriesPath);
            
            app.samplePath = samplePath;
            app.pillarPath = pillarPath;
            app.seriesPath = seriesPath;
            
            if strcmp(imagesDirection, 'x')
                app.xButton.Value = true;
                app.imagesDirection = 'x';
            elseif strcmp(imagesDirection, 'y')
                app.yButton.Value = true;
                app.imagesDirection = 'y';
            else
                app.FourtyFiveButton.Value = true;
                app.imagesDirection = '45';
            end

            app.mainapp = mainapp;
        end

        % Button pushed function: BrowseSampleButton
        function BrowseSampleCallback(app, event)
            app.samplePath = uigetdir('', "Select the sample directory.");

            if app.samplePath == 0 | isempty(app.samplePath)
                return
            end

            cd(app.samplePath)
            
            [~, app.CurrentSampleEditField.Value] = fileparts(app.samplePath);
            app.CurrentPillarEditField.Value = '';
            app.CurrentSeriesEditField.Value = '';

            app.workFolder = app.samplePath;
        end

        % Button pushed function: BrowsePillarButton
        function BrowsePillarCallback(app, event)
            app.pillarPath = uigetdir('', "Select the pillar directory.");

            if app.pillarPath == 0 | isempty(app.pillarPath)
                return
            end

            cd(app.pillarPath)

            [app.samplePath, app.PillarPathEditField.Value] = fileparts(app.pillarPath);
            [~, app.SamplePathEditField.Value] = fileparts(app.samplePath);
            app.SeriesPathEditField.Value = '';

            app.workFolder = app.pillarPath;
        end

        % Button pushed function: BrowseSeriesButton
        function BrowseSeriesCallback(app, event)
            app.seriesPath = uigetdir('','Select the series directory.');

            if app.seriesPath == 0 | isempty(app.seriesPath)
                return
            end

            cd(app.seriesPath)

            [app.pillarPath, app.SeriesPathEditField.Value] = fileparts(app.seriesPath);
            [~,app.PillarPathEditField.Value] = fileparts(app.pillarPath);
            
            [app.samplePath,~] = fileparts(app.pillarPath);
            [~,app.SamplePathEditField.Value] = fileparts(app.samplePath);

            app.workFolder = fullfile(app.seriesPath,'processed',app.imagesDirection);
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            app.mainapp.samplePath = app.samplePath;
            app.mainapp.pillarPath = app.pillarPath;
            app.mainapp.seriesPath = app.seriesPath;

            if app.xButton.Value == true
                app.mainapp.imagesDirection = 'x';
            elseif app.yButton.Value == true
                app.mainapp.imagesDirection = 'y';
            else
                app.mainapp.imagesDirection = '45';
            end
            
            app.mainapp.workFolder = app.workFolder;

            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end

        % Selection changed function: ImagesDirectionButtonGroup
        function ImagesDirectionChangeCallback(app, event)
            if app.xButton.Value == true
                app.imagesDirection = 'x';
            elseif app.yButton.Value == true
                app.imagesDirection = 'y';
            else
                app.imagesDirection = '45';
            end

            if ~isempty(app.workFolder)
                app.workFolder = fullfile(app.seriesPath,'processed',app.imagesDirection);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DataSelectionUIFigure = uifigure('Visible', 'off');
            app.DataSelectionUIFigure.Name = 'Data selection';
            app.DataSelectionUIFigure.Icon = fullfile(pathToMLAPP, 'DP_app_resources', 'icon-DP_app.png');
            app.CancelButton = uibutton(app.DataSelectionUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.DataSelectionUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.ImagesDirectionButtonGroup = uibuttongroup(app.DataSelectionUIFigure);
            app.ImagesDirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImagesDirectionChangeCallback, true);
            app.ImagesDirectionButtonGroup.Title = 'Direction of the images';
            app.FourtyFiveButton = uiradiobutton(app.ImagesDirectionButtonGroup);
            app.FourtyFiveButton.Text = '45°';
            app.yButton = uiradiobutton(app.ImagesDirectionButtonGroup);
            app.yButton.Text = 'y';
            app.xButton = uiradiobutton(app.ImagesDirectionButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.BrowseSeriesButton = uibutton(app.DataSelectionUIFigure, 'push');
            app.BrowseSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSeriesCallback, true);
            app.BrowseSeriesButton.Text = 'Browse...';
            app.SeriesPathEditField = uieditfield(app.DataSelectionUIFigure, 'text');
            app.SelectedseriesLabel = uilabel(app.DataSelectionUIFigure);
            app.SelectedseriesLabel.Text = 'Sample directory';
            app.BrowsePillarButton = uibutton(app.DataSelectionUIFigure, 'push');
            app.BrowsePillarButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsePillarCallback, true);
            app.BrowsePillarButton.Text = 'Browse...';
            app.CurrentpillarLabel = uilabel(app.DataSelectionUIFigure);
            app.CurrentpillarLabel.Text = 'Pillar directory';
            app.PillarPathEditField = uieditfield(app.DataSelectionUIFigure, 'text');
            app.PillarPathEditField.Editable = 'off';
            app.BrowseSampleButton = uibutton(app.DataSelectionUIFigure, 'push');
            app.BrowseSampleButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSampleCallback, true);
            app.BrowseSampleButton.Text = 'Browse...';
            app.SamplePathEditField = uieditfield(app.DataSelectionUIFigure, 'text');
            app.SamplePathEditField.Editable = 'off';
            app.CurrentseriesLabel = uilabel(app.DataSelectionUIFigure);
            app.CurrentseriesLabel.Text = 'Series directory';
            % Show the figure after all components are created
            app.DataSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataSelectionApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DataSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DataSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataSelectionUIFigure)
        end
    end
end