classdef GraphPreview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraphPreviewFigure         matlab.ui.Figure
        CancelButton               matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
        RemovePlotsButton          matlab.ui.control.Button
        ImportDataButton           matlab.ui.control.Button
        ResidualStressLimitsPanel  matlab.ui.container.Panel
        yHighBoundLabel            matlab.ui.control.Label
        yAxisHighLimitEditField    matlab.ui.control.NumericEditField
        yAxisLowLimitEditField     matlab.ui.control.NumericEditField
        yLowBoundLabel             matlab.ui.control.Label
        DepthLimitsPanel           matlab.ui.container.Panel
        UpperlimitLabel            matlab.ui.control.Label
        xAxisHighLimitEditField    matlab.ui.control.NumericEditField
        xAxisLowLimitEditField     matlab.ui.control.NumericEditField
        PointsspanLabel            matlab.ui.control.Label
        xAxisDataButtonGroup       matlab.ui.container.ButtonGroup
        NormalizedDepthButton      matlab.ui.control.RadioButton
        DepthButton                matlab.ui.control.RadioButton
        AxesPreview                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file
        path

        depth
        normalizedDepth
        residualStress
        stdDevX
        stdDevY
        pillarDiameter

        currentPlot
        currentFill

        xAxisLowLimit
        xAxisHighLimit

        yAxisLowLimit
        yAxisHighLimit

        mainapp
    end
    
    methods (Access = private)
        function limitsChangesCallback(app)
            app.xAxisLowLimitEditField.Value = app.AxesPreview.XLim(1);
            app.xAxisHighLimitEditField.Value = app.AxesPreview.XLim(2);

            app.yAxisLowLimitEditField.Value = app.AxesPreview.YLim(1);
            app.yAxisHighLimitEditField.Value = app.AxesPreview.YLim(2);
        end
        
        function plotPillar(app)
            if app.DepthButton.Value == true
                app.currentPlot = plot(app.AxesPreview, app.depth, app.residualStress, 'LineWidth',3);
                hold(app.AxesPreview,"on")
                app.currentFill = fill(app.AxesPreview, app.stdDevX*app.pillarDiameter, app.stdDevY, 'b', 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                hold(app.AxesPreview,"off")
                legend(app.AxesPreview,'Location','best')
            else
                app.currentPlot = plot(app.AxesPreview, app.normalizedDepth, app.residualStress, 'LineWidth',3);
                hold(app.AxesPreview,"on")
                app.currentFill = fill(app.AxesPreview, app.stdDevX, app.stdDevY, 'b', 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                hold(app.AxesPreview,"off")
                legend(app.AxesPreview,'Location','best')
            end
        end
        
        function plotSeries(app)
            if app.DepthButton.Value == true
                app.currentPlot = plot(app.AxesPreview,app.depth,app.residualStress,'LineWidth',3);
            else
                app.currentPlot = plot(app.AxesPreview,app.normalizedDepth,app.residualStress,'LineWidth',3);
            end
            legend(app.AxesPreview,'Location','best')
        end
        
        function plotSample(app)
            app.currentPlot = plot(app.AxesPreview, app.depth, app.residualStress, 'LineWidth',3);
            hold(app.AxesPreview,"on")
            app.currentFill = fill(app.AxesPreview, app.stdDevX, app.stdDevY, 'b', 'FaceAlpha', 0.2, 'EdgeColor', 'none');
            hold(app.AxesPreview,"off")
            legend(app.AxesPreview,'Location','best')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, xAxisData, xAxisLowLimit, xAxisHighLimit, yAxisLowLimit, yAxisHighLimit)
            if strcmp(xAxisData, 'Depth')
                app.DepthButton.Value = true;
            else
                app.NormalizedDepthButton.Value = true;
            end
            
            app.xAxisLowLimitEditField.Value = xAxisLowLimit;
            app.xAxisLowLimit = xAxisLowLimit;
            
            app.xAxisHighLimitEditField.Value = xAxisHighLimit;
            app.xAxisHighLimit = xAxisHighLimit;

            app.yAxisLowLimitEditField.Value = yAxisLowLimit;
            app.yAxisLowLimit = yAxisLowLimit;

            app.yAxisHighLimitEditField.Value = yAxisHighLimit;
            app.yAxisHighLimit = yAxisHighLimit;

            app.mainapp = mainapp;

            xAxisDataChangedCallback(app)
            xAxisLimitsChangeCallback(app)
            yAxisLimitsChangeCallback(app)

            % Listeners for changes in limits of the graph
            addlistener(app.AxesPreview, 'XLim', 'PostSet', @(~,~) limitsChangesCallback(app));
            addlistener(app.AxesPreview, 'YLim', 'PostSet', @(~,~) limitsChangesCallback(app));
        end

        % Selection changed function: xAxisDataButtonGroup
        function xAxisDataChangedCallback(app, event)
            if app.DepthButton.Value == true
                % Change fields with x axis limits
                app.DepthLimitsPanel.Title = "Depth limits [µm]";
                app.AxesPreview.XLabel.String = app.DepthButton.Text;                
            else
                app.DepthLimitsPanel.Title = "Normalized depth limits [no unit]";
                app.AxesPreview.XLabel.String = app.NormalizedDepthButton.Text;
            end

            % Change graph
            app.AxesPreview.XLim = [app.xAxisLowLimitEditField.Value app.xAxisHighLimitEditField.Value];

            % Change plot accordingly
            if strcmp(app.file,"residualStress-0.mat") | strcmp(app.file,"residualStress-45.mat") | strcmp(app.file,"residualStress-90.mat")
                plotSeries(app)
            elseif strcmp(app.file,"meanRS.mat")
                plotPillar(app)
            elseif strcmp(app.file,"strung_data-combined.mat")
                plotSample(app)
            end
        end

        % Value changed function: xAxisHighLimitEditField, 
        % ...and 1 other component
        function xAxisLimitsChangeCallback(app, event)
            % Check value of the limits
            if app.xAxisLowLimitEditField.Value > app.xAxisHighLimitEditField.Value
                invalidLimitsAlert = uiconfirm(app.DPParametersUIFigure,'Verify that the low limit is lower than the high limit.','Invalid limits', ...
                    'Options','OK','Icon','warning');
                waitfor(invalidLimitsAlert)
                app.xAxisLowLimitEditField.Value = app.xAxisLowLimit;
                app.xAxisHighLimitEditField.Value = app.xAxisHighLimit;
            else
                app.AxesPreview.XLim = [app.xAxisLowLimitEditField.Value app.xAxisHighLimitEditField.Value];
                app.xAxisLowLimit = app.xAxisLowLimitEditField.Value;
                app.xAxisHighLimit = app.xAxisHighLimitEditField.Value;
            end

            % Reposition legend
            legend(app.AxesPreview,'Location','best')
        end

        % Value changed function: yAxisHighLimitEditField, 
        % ...and 1 other component
        function yAxisLimitsChangeCallback(app, event)
            if app.yAxisLowLimitEditField.Value > app.yAxisHighLimitEditField.Value
                invalidLimitsAlert = uiconfirm(app.DPParametersUIFigure,'Verify that the low limit is lower than the high limit.','Invalid limits', ...
                    'Options','OK','Icon','warning');
                waitfor(invalidLimitsAlert)
                app.yAxisLowLimitEditField.Value = app.yAxisLowLimit;
                app.yAxisHighLimitEditField.Value = app.yAxisHighLimit;
            else
                app.AxesPreview.YLim = [app.yAxisLowLimitEditField.Value app.yAxisHighLimitEditField.Value];
                app.yAxisLowLimit = app.yAxisLowLimitEditField.Value;
                app.yAxisHighLimit = app.yAxisHighLimitEditField.Value;
            end

            % Reposition legend
            legend(app.AxesPreview,'Location','best')
        end

        % Button pushed function: ImportDataButton
        function ImportDataCallback(app, event)
            [app.file, app.path] = uigetfile('*.mat','Select the data file.');
            if app.file == 0 | isempty(app.file)
                return
            else
                stressFile = load(fullfile(app.path,app.file));
            end

            if strcmp(app.file,"residualStress-0.mat") | strcmp(app.file,"residualStress-45.mat") | strcmp(app.file,"residualStress-90.mat")
                app.NormalizedDepthButton.Enable = "on";
                
                app.depth = stressFile.depth;
                app.normalizedDepth = stressFile.normalizedDepth;
                app.residualStress = stressFile.stress;
                
                plotSeries(app)
            elseif strcmp(app.file,"meanRS.mat")
                app.NormalizedDepthButton.Enable = "on";

                app.depth = stressFile.depth;
                app.normalizedDepth = stressFile.normalizedDepth;
                app.residualStress = stressFile.meanRS;
                app.stdDevX = stressFile.stdDevX;
                app.stdDevY = stressFile.stdDevY;
                app.pillarDiameter = stressFile.pillarDiameter;
    
                limitsChangesCallback(app)
                plotPillar(app)
            elseif strcmp(app.file,"strung_data-combined.mat")
                app.DepthButton.Value = true;
                app.NormalizedDepthButton.Enable = "off";
                xAxisDataChangedCallback(app)

                app.depth = stressFile.strungData.allDepths;
                app.residualStress = stressFile.strungData.allStresses;
                app.stdDevX = stressFile.strungData.allStdDevsX;
                app.stdDevY = stressFile.strungData.allStdDevsY;

                plotSample(app)
            end
            % get(app.AxesPreview,'Children')
        end

        % Button pushed function: RemovePlotsButton
        function RemovePlotsCallback(app, event)
            cla(app.AxesPreview)
        end

        % Button pushed function: OKButton
        function OKCallback(app, event)
            % x axis data
            if app.DepthButton.Value == true
                app.mainapp.xAxisData = "Depth";
            else
                app.mainapp.xAxisData = "Normalized depth";
            end
            
            % Plotting options
            app.mainapp.xAxisLowLimit = app.xAxisLowLimitEditField.Value;
            app.mainapp.xAxisHighLimit = app.xAxisHighLimitEditField.Value;
            app.mainapp.yAxisLowLimit = app.yAxisLowLimitEditField.Value;
            app.mainapp.yAxisHighLimit = app.yAxisHighLimitEditField.Value;

            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelCallback(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraphPreviewFigure = uifigure('Visible', 'off');
            app.GraphPreviewFigure.Name = 'MATLAB App';
            app.AxesPreview = uiaxes(app.GraphPreviewFigure);
            title(app.AxesPreview, 'Axes preview')
            xlabel(app.AxesPreview, 'Normalized depth')
            ylabel(app.AxesPreview, 'Residual stress [MPa]')
            zlabel(app.AxesPreview, 'Z')
            app.AxesPreview.XLim = [0 0.2];
            app.AxesPreview.YLim = [-Inf Inf];
            app.xAxisDataButtonGroup = uibuttongroup(app.GraphPreviewFigure);
            app.xAxisDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @xAxisDataChangedCallback, true);
            app.xAxisDataButtonGroup.Tooltip = {''};
            app.xAxisDataButtonGroup.Title = 'x axis data';
            app.DepthButton = uiradiobutton(app.xAxisDataButtonGroup);
            app.DepthButton.Text = 'Depth [µm]';
            app.NormalizedDepthButton = uiradiobutton(app.xAxisDataButtonGroup);
            app.NormalizedDepthButton.Tooltip = {'depth/(pillar diameter)'};
            app.NormalizedDepthButton.Text = 'Normalized depth';
            app.NormalizedDepthButton.Value = true;
            app.DepthLimitsPanel = uipanel(app.GraphPreviewFigure);
            app.DepthLimitsPanel.Title = 'Normalized depth limits [no unit]';
            app.PointsspanLabel = uilabel(app.DepthLimitsPanel);
            app.PointsspanLabel.Tooltip = {'Specify the limits as -inf and inf if you want them to be set automatically.'};
            app.PointsspanLabel.Text = 'Low limit';
            app.xAxisLowLimitEditField = uieditfield(app.DepthLimitsPanel, 'numeric');
            app.xAxisLowLimitEditField.ValueChangedFcn = createCallbackFcn(app, @xAxisLimitsChangeCallback, true);
            app.xAxisLowLimitEditField.Tooltip = {'Specify the limits as -inf and inf if you want them to be set automatically.'};
            app.xAxisHighLimitEditField = uieditfield(app.DepthLimitsPanel, 'numeric');
            app.xAxisHighLimitEditField.ValueChangedFcn = createCallbackFcn(app, @xAxisLimitsChangeCallback, true);
            app.xAxisHighLimitEditField.Tooltip = {'Specify the limits as -inf and inf if you want them to be set automatically.'};
            app.xAxisHighLimitEditField.Value = 0.2;
            app.UpperlimitLabel = uilabel(app.DepthLimitsPanel);
            app.UpperlimitLabel.Tooltip = {'Specify the limits as -inf and inf if you want them to be set automatically.'};
            app.UpperlimitLabel.Text = 'High limit';
            app.ResidualStressLimitsPanel = uipanel(app.GraphPreviewFigure);
            app.ResidualStressLimitsPanel.Title = 'Residual stress limits [MPa]';
            app.yLowBoundLabel = uilabel(app.ResidualStressLimitsPanel);
            app.yLowBoundLabel.Tooltip = {'Specify the limits as -inf and inf if you want them to be set automatically.'};
            app.yLowBoundLabel.Text = 'Low limit';
            app.yAxisLowLimitEditField = uieditfield(app.ResidualStressLimitsPanel, 'numeric');
            app.yAxisLowLimitEditField.ValueChangedFcn = createCallbackFcn(app, @yAxisLimitsChangeCallback, true);
            app.yAxisLowLimitEditField.Tooltip = {'Specify the limits as -inf and inf if you want them to be set automatically.'};
            app.yAxisLowLimitEditField.Value = -Inf;
            app.yAxisHighLimitEditField = uieditfield(app.ResidualStressLimitsPanel, 'numeric');
            app.yAxisHighLimitEditField.ValueChangedFcn = createCallbackFcn(app, @yAxisLimitsChangeCallback, true);
            app.yAxisHighLimitEditField.Tooltip = {'Specify the limits as -inf and inf if you want them to be set automatically.'};
            app.yAxisHighLimitEditField.Value = Inf;
            app.yHighBoundLabel = uilabel(app.ResidualStressLimitsPanel);
            app.yHighBoundLabel.Tooltip = {'Specify the limits as -inf and inf if you want them to be set automatically.'};
            app.yHighBoundLabel.Text = 'High limit';
            app.ImportDataButton = uibutton(app.GraphPreviewFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataCallback, true);
            app.ImportDataButton.Text = 'Import data';
            app.RemovePlotsButton = uibutton(app.GraphPreviewFigure, 'push');
            app.RemovePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePlotsCallback, true);
            app.RemovePlotsButton.Text = 'Remove all plots';
            app.OKButton = uibutton(app.GraphPreviewFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKCallback, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GraphPreviewFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelCallback, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.GraphPreviewFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphPreview(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraphPreviewFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraphPreviewFigure)
        end
    end
end